classdef RLC_Circuit < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        CalculateButton                matlab.ui.control.Button
        Image                          matlab.ui.control.Image
        RLC_CircuitLabel               matlab.ui.control.Label
        InductorPanel                  matlab.ui.container.Panel
        VoltageEditField_2Label_7      matlab.ui.control.Label
        VoltageEditField_2Label_6      matlab.ui.control.Label
        CurrentEditField_3             matlab.ui.control.NumericEditField
        CurrentEditField_3Label        matlab.ui.control.Label
        VoltageEditField_5             matlab.ui.control.NumericEditField
        VoltageEditField_5Label        matlab.ui.control.Label
        CapacitorPanel                 matlab.ui.container.Panel
        VoltageEditField_2Label_5      matlab.ui.control.Label
        VoltageEditField_2Label_4      matlab.ui.control.Label
        CurrentEditField_2             matlab.ui.control.NumericEditField
        CurrentEditField_2Label        matlab.ui.control.Label
        VoltageEditField_4             matlab.ui.control.NumericEditField
        VoltageEditField_4Label        matlab.ui.control.Label
        ResistorPanel                  matlab.ui.container.Panel
        VoltageEditField_2Label_3      matlab.ui.control.Label
        VoltageEditField_2Label_2      matlab.ui.control.Label
        CurrentEditField               matlab.ui.control.NumericEditField
        CurrentEditFieldLabel          matlab.ui.control.Label
        VoltageEditField_2             matlab.ui.control.NumericEditField
        VoltageEditField_2Label        matlab.ui.control.Label
        TimeEditField                  matlab.ui.control.NumericEditField
        TimeEditFieldLabel             matlab.ui.control.Label
        InductanceEditField            matlab.ui.control.NumericEditField
        InductanceEditFieldLabel       matlab.ui.control.Label
        CapacitanceEditField           matlab.ui.control.NumericEditField
        CapacitanceEditFieldLabel      matlab.ui.control.Label
        ResistanceEditField            matlab.ui.control.NumericEditField
        ResistanceEditFieldLabel       matlab.ui.control.Label
        VoltageEditField               matlab.ui.control.NumericEditField
        VoltageEditFieldLabel          matlab.ui.control.Label
        ElementsinParallelButtonGroup  matlab.ui.container.ButtonGroup
        ResistorInductorButton_2       matlab.ui.control.RadioButton
        ResistorCapacitorButton        matlab.ui.control.RadioButton
        AllSeriesButton                matlab.ui.control.RadioButton
        CapacitorInductorButton_2      matlab.ui.control.RadioButton
        AllElementsButton              matlab.ui.control.RadioButton
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end

    
    methods (Access = private)
        
        function CalculateButtonPushed(app, event)
            % Inputs
            V  = app.VoltageEditField.Value;      % Source Voltage
            R  = app.ResistanceEditField.Value;   % Ohms
            C  = app.CapacitanceEditField.Value;  % Farads
            L  = app.InductanceEditField.Value;   % Henry
            t  = app.TimeEditField.Value;         % Time
            w  = 2*pi*(1/t);                      % Omega

            % Complex impedances
            ZR = R;
            if C > 0
              ZC = 1/(1j*w*C);
            else
              ZC = inf;
            end
            if L > 0
              ZL = 1j*w*L;
            else
              ZL = inf;
            end

            % Initialize outputs
            VR=0; IR=0; VC=0; IC=0; VL=0; IL=0;

            % Which circuit is selected?
            selected = app.ElementsinParallelButtonGroup.SelectedObject.Text;

           switch selected
               case 'AllSeries'
                 % All three elements are in SERIES (R - L - C)
                 Ztotal = ZR + ZL + ZC;
                 I_total = V / Ztotal;   % same current in series

                 % Voltage across each element
                 VR = I_total * ZR;
                 VL = I_total * ZL;
                 VC = I_total * ZC;

                 % Currents are same
                 IR = I_total;
                 IL = I_total;
                 IC = I_total;

              case 'AllElements'
                 % R || C || L
                 Ztotal = 1/(1/ZR + 1/ZC + 1/ZL);
                 I_total = V/Ztotal;
                 VR = V; VC = V; VL = V;
                 IR = VR/ZR; IC = VC/ZC; IL = VL/ZL;

              case 'Resistor&Capacitor'
                 % (R || C) in series with L
                 Z_RC = 1/(1/ZR + 1/ZC);
                 Ztotal = Z_RC + ZL;
                 I_total = V/Ztotal;

                 % Voltage across RC branch
                 V_RC = I_total * Z_RC;
                 VR = V_RC; VC = V_RC;
                 IR = VR/ZR; IC = VC/ZC;

                 % Voltage & Current across Inductor
                 VL = I_total * ZL;
                 IL = I_total;

              case 'Resistor&Inductor'
                  % (R || L) are in parallel & C in series
                  Z_RL = (ZR * ZL)/(ZR + ZL);
                  Ztotal = Z_RL + ZC;

                  % Source Current
                  I_total = V/Ztotal;

                  % Voltage across RL branch
                  V_RL = I_total * Z_RL;
                  VR = V_RL; VL = V_RL; 
                  IR = VR/ZR; IL = VL/ZL;

                  % Voltage & Current across Capacitor
                  VC = I_total * ZC;
                  IC = I_total;

              case 'Capacitor&Inductor'
                  % (C || L) in series with R
                  Z_CL = 1/(1/ZC + 1/ZL);
                  Ztotal = ZR + Z_CL;
                  I_total = V/Ztotal;

                  % Voltage across CL branch
                  V_CL = I_total * Z_CL;
                  VC = V_CL; VL = V_CL;
                  IC = VC/ZC; IL = VL/ZL;

                  % Voltage & Current across Resistor
                  VR = I_total * ZR;
                  IR = I_total;
            end

            % Update output fields (magnitudes only!)
            app.VoltageEditField_2.Value = abs(VR);
            app.CurrentEditField.Value   = abs(IR);

            app.VoltageEditField_4.Value = abs(VC);
            app.CurrentEditField_2.Value = abs(IC);

            app.VoltageEditField_5.Value = abs(VL);
            app.CurrentEditField_3.Value = abs(IL);
        end


        % Selection changed function: ElementsinParallelButtonGroup
        function ElementsinParallelSelectionChanged(app, event)
            % Path to this .mlapp file
            pathToMLAPP = fileparts(mfilename('fullpath'));

            selected = app.ElementsinParallelButtonGroup.SelectedObject.Text;

            switch selected
                case 'AllElements'
                    app.Image.ImageSource = fullfile(pathToMLAPP, 'CircuitIm', 'AllElements.png');

                case 'Capacitor&Inductor'
                    app.Image.ImageSource = fullfile(pathToMLAPP, 'CircuitIm', 'Capacitor&Inductor.png');

                case 'AllSeries'
                    app.Image.ImageSource = fullfile(pathToMLAPP, 'CircuitIm', 'AllSeries.png');

                case 'Resistor&Capacitor'
                    app.Image.ImageSource = fullfile(pathToMLAPP, 'CircuitIm', 'Resistor&Capacitor.png');

                case 'Resistor&Inductor'
                    app.Image.ImageSource = fullfile(pathToMLAPP, 'CircuitIm', 'Resistor&Inductor.png');
            end
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalculateButton
        function CalculateButtonPushed2(app, event)
            app.CalculateButton.ButtonPushedFcn = ...
    createCallbackFcn(app, @CalculateButtonPushed, true);
        end

        % Selection changed function: ElementsinParallelButtonGroup
        function ElementsinParallelButtonGroupSelectionChanged(app, event)
            %selectedButton = app.ElementsinParallelButtonGroup.SelectedObject;
            app.ElementsinParallelButtonGroup.SelectionChangedFcn = ...
    createCallbackFcn(app, @ElementsinParallelSelectionChanged, true);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ElementsinParallelButtonGroup = uibuttongroup(app.UIFigure);
            app.ElementsinParallelButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ElementsinParallelButtonGroupSelectionChanged, true);
            app.ElementsinParallelButtonGroup.Title = 'Elements in Parallel';
            app.AllElementsButton = uiradiobutton(app.ElementsinParallelButtonGroup);
            app.AllElementsButton.Text = 'AllElements';
            app.AllElementsButton.Value = true;
            app.CapacitorInductorButton_2 = uiradiobutton(app.ElementsinParallelButtonGroup);
            app.CapacitorInductorButton_2.Text = 'Capacitor&Inductor';
            app.AllSeriesButton = uiradiobutton(app.ElementsinParallelButtonGroup);
            app.AllSeriesButton.Text = 'AllSeries';
            app.ResistorCapacitorButton = uiradiobutton(app.ElementsinParallelButtonGroup);
            app.ResistorCapacitorButton.Text = 'Resistor&Capacitor';
            app.ResistorInductorButton_2 = uiradiobutton(app.ElementsinParallelButtonGroup);
            app.ResistorInductorButton_2.Text = 'Resistor&Inductor';
            app.VoltageEditFieldLabel = uilabel(app.UIFigure);
            app.VoltageEditFieldLabel.Text = 'Voltage';
            app.VoltageEditField = uieditfield(app.UIFigure, 'numeric');
            app.ResistanceEditFieldLabel = uilabel(app.UIFigure);
            app.ResistanceEditFieldLabel.Text = 'Resistance';
            app.ResistanceEditField = uieditfield(app.UIFigure, 'numeric');
            app.CapacitanceEditFieldLabel = uilabel(app.UIFigure);
            app.CapacitanceEditFieldLabel.Text = 'Capacitance';
            app.CapacitanceEditField = uieditfield(app.UIFigure, 'numeric');
            app.InductanceEditFieldLabel = uilabel(app.UIFigure);
            app.InductanceEditFieldLabel.Text = 'Inductance';
            app.InductanceEditField = uieditfield(app.UIFigure, 'numeric');
            app.TimeEditFieldLabel = uilabel(app.UIFigure);
            app.TimeEditFieldLabel.Text = 'Time';
            app.TimeEditField = uieditfield(app.UIFigure, 'numeric');
            app.ResistorPanel = uipanel(app.UIFigure);
            app.ResistorPanel.Title = 'Resistor';
            app.VoltageEditField_2Label = uilabel(app.ResistorPanel);
            app.VoltageEditField_2Label.Text = 'Voltage';
            app.VoltageEditField_2 = uieditfield(app.ResistorPanel, 'numeric');
            app.CurrentEditFieldLabel = uilabel(app.ResistorPanel);
            app.CurrentEditFieldLabel.Text = 'Current';
            app.CurrentEditField = uieditfield(app.ResistorPanel, 'numeric');
            app.VoltageEditField_2Label_2 = uilabel(app.ResistorPanel);
            app.VoltageEditField_2Label_2.Text = 'V';
            app.VoltageEditField_2Label_3 = uilabel(app.ResistorPanel);
            app.VoltageEditField_2Label_3.Text = 'A';
            app.CapacitorPanel = uipanel(app.UIFigure);
            app.CapacitorPanel.Title = 'Capacitor';
            app.VoltageEditField_4Label = uilabel(app.CapacitorPanel);
            app.VoltageEditField_4Label.Text = 'Voltage';
            app.VoltageEditField_4 = uieditfield(app.CapacitorPanel, 'numeric');
            app.CurrentEditField_2Label = uilabel(app.CapacitorPanel);
            app.CurrentEditField_2Label.Text = 'Current';
            app.CurrentEditField_2 = uieditfield(app.CapacitorPanel, 'numeric');
            app.VoltageEditField_2Label_4 = uilabel(app.CapacitorPanel);
            app.VoltageEditField_2Label_4.Text = 'V';
            app.VoltageEditField_2Label_5 = uilabel(app.CapacitorPanel);
            app.VoltageEditField_2Label_5.Text = 'A';
            app.InductorPanel = uipanel(app.UIFigure);
            app.InductorPanel.Title = 'Inductor';
            app.VoltageEditField_5Label = uilabel(app.InductorPanel);
            app.VoltageEditField_5Label.Text = 'Voltage';
            app.VoltageEditField_5 = uieditfield(app.InductorPanel, 'numeric');
            app.CurrentEditField_3Label = uilabel(app.InductorPanel);
            app.CurrentEditField_3Label.Text = 'Current';
            app.CurrentEditField_3 = uieditfield(app.InductorPanel, 'numeric');
            app.VoltageEditField_2Label_6 = uilabel(app.InductorPanel);
            app.VoltageEditField_2Label_6.Text = 'V';
            app.VoltageEditField_2Label_7 = uilabel(app.InductorPanel);
            app.VoltageEditField_2Label_7.Text = 'A';
            app.RLC_CircuitLabel = uilabel(app.UIFigure);
            app.RLC_CircuitLabel.Text = 'RLC_Circuit';
            app.Image = uiimage(app.UIFigure);
            app.CalculateButton = uibutton(app.UIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed2, true);
            app.CalculateButton.Text = 'Calculate';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RLC_Circuit

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end